classdef AppFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CargarSegunaImagenButton        matlab.ui.control.Button
        DescargarButton                 matlab.ui.control.Button
        ReiniciarEdicionButton          matlab.ui.control.Button
        CargarImagenButton              matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        MejoraImagenButton              matlab.ui.control.Button
        SepararComponentesButton        matlab.ui.control.Button
        BInarizacionButton              matlab.ui.control.Button
        ProcesamientoBasicoLabel        matlab.ui.control.Label
        EscaladeGrisesButton            matlab.ui.control.Button
        Tab2                            matlab.ui.container.Tab
        ResultadoVecinosLabel           matlab.ui.control.Label
        ResultadoDistanciasLabel        matlab.ui.control.Label
        Y1_VecinosEditField             matlab.ui.control.NumericEditField
        Y1EditField_2Label              matlab.ui.control.Label
        X1_VecinosEditField             matlab.ui.control.NumericEditField
        X1EditField_2Label              matlab.ui.control.Label
        Y2EditField                     matlab.ui.control.NumericEditField
        Y2EditFieldLabel                matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        CalcularLogicaCompuestaButton   matlab.ui.control.Button
        CalcularLogicaButton            matlab.ui.control.Button
        CalcularGeometricasButton       matlab.ui.control.Button
        TraslacionLabel                 matlab.ui.control.Label
        DeltaYEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        DeltaXEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        FactorEditField                 matlab.ui.control.NumericEditField
        EscalamientoEditFieldLabel      matlab.ui.control.Label
        RotacinEditFieldLabel           matlab.ui.control.Label
        GradosEditField                 matlab.ui.control.NumericEditField
        CalcularAritmeticaButton        matlab.ui.control.Button
        ValorConstanteEditField         matlab.ui.control.NumericEditField
        ValorConstanteLabel             matlab.ui.control.Label
        Y1EditField                     matlab.ui.control.NumericEditField
        Y1EditFieldLabel                matlab.ui.control.Label
        LogicasCompuestasDropDown       matlab.ui.control.DropDown
        LogicasCompuestasDropDownLabel  matlab.ui.control.Label
        LogicasDropDown                 matlab.ui.control.DropDown
        LogicasDropDownLabel            matlab.ui.control.Label
        GeometricasDropDown             matlab.ui.control.DropDown
        GeometricasDropDownLabel        matlab.ui.control.Label
        AritmeticasDropDown             matlab.ui.control.DropDown
        AritmeticasDropDownLabel        matlab.ui.control.Label
        MostrarVecinosButton            matlab.ui.control.Button
        CalcularDIstanciaButton         matlab.ui.control.Button
        VecindadDropDown                matlab.ui.control.DropDown
        VecindadDropDownLabel           matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X1EditFieldLabel                matlab.ui.control.Label
        DistanciasDropDown              matlab.ui.control.DropDown
        DistanciaDropDownLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Tab3                            matlab.ui.container.Tab
        AplicarFiltroButton             matlab.ui.control.Button
        FiltroDropDown                  matlab.ui.control.DropDown
        FiltroDropDownLabel             matlab.ui.control.Label
        AplicarRuidoButton              matlab.ui.control.Button
        IntensidadDropDownLabel         matlab.ui.control.Label
        IntensidadDropDown              matlab.ui.control.DropDown
        RuidoDropDown                   matlab.ui.control.DropDown
        RuidoDropDownLabel              matlab.ui.control.Label
        RuidoyFIltrosLabel              matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
     properties (Access = private)
        ImagenOriginal      % Almacena la matriz de la imagen cargada
        ImagenProcesada     % Almacena la matriz en proceso actual
        ImagenConRuido      % Almacena la matriz contaminada con ruido
        ImagenGris
        X_size              % Almacena el valor de las filas de una imagen
        Y_size              % Almacena el valor de las columnas de una imagen
        ImagenOriginal2     % Almacena la segunda fotografia
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EscaladeGrisesButton
        function EscaladeGrisesButtonPushed(app, event)
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero debes cargar una imagen.', 'Advertencia');
                return;
            end
            % Mandar a llamar a la funcion manual de Grises
            app.ImagenProcesada = obtenerGrisManual(app.ImagenOriginal);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes);
        end

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
            [archivo, ruta] = uigetfile({'*.jpg;*.png;*.bmp','Archivos de Imagen'});
            if archivo ~= 0
                rutaCompleta = fullfile(ruta, archivo);
                app.ImagenOriginal = imread(rutaCompleta);
                app.ImagenProcesada = app.ImagenOriginal; 
                
                % 1. Mostrar la imagen original y prender las reglas de los ejes
                imshow(app.ImagenOriginal, 'Parent', app.UIAxes);
                axis(app.UIAxes, 'on'); % Prende la regla visual
                
                % 2. CÁLCULO MANUAL DE DIMENSIONES (Piedritas y palitos)
                app.X_size = 0;
                app.Y_size = 0;
                
                % Contar columnas (Eje X)
                for x = app.ImagenOriginal(1, 1:end, 1)
                    app.X_size = app.X_size + 1;
                end
                
                % Contar filas (Eje Y)
                for y = app.ImagenOriginal(1:end, 1, 1)'
                    app.Y_size = app.Y_size + 1;
                end
                
                % 3. Actualizar etiqueta visual para el usuario (Opcional, si agregaste el RangoLabel)
                % Quítale el símbolo de % a la siguiente línea si creaste el texto "RangoLabel" en tu interfaz:
                % app.RangoLabel.Text = sprintf('Rango válido: X (1 a %d) | Y (1 a %d)', app.X_size, app.Y_size);
                
                disp('Imagen cargada y dimensiones calculadas correctamente.');
            end
        end

        % Button pushed function: BInarizacionButton
        function BInarizacionButtonPushed(app, event)
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, 'No hay imagen procesada para binarizar.', 'Advertencia');
                return;
            end

            imgActual = app.ImagenProcesada;
            if size(imgActual, 3) == 3
                imgActual = obtenerGrisManual(imgActual);
            end

            % Leer el umbral del campo de texto numérico
            umbralUsuario = app.EditField.Value;

            % Mandar a llamar tu función independiente de umbral/binario
            app.ImagenProcesada = binarizarManual(imgActual, umbralUsuario);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes);
        end

        % Button pushed function: SepararComponentesButton
        function SepararComponentesButtonPushed(app, event)
            % 1. Verificamos que exista una imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Por favor carga una imagen primero.', 'Advertencia');
                return;
            end
            
            % 2. Verificamos que sea a color
            try
                pruebaCanal = app.ImagenOriginal(1,1,3); 
            catch
                uialert(app.UIFigure, 'La imagen cargada no tiene colores RGB para separar.', 'Aviso');
                return;
            end
            
            % 3. Mandar a llamar la librería blindada (¡Ya no necesitamos pasarle app.X_size!)
            [ImgRed, ImgGreen, ImgBlue] = ExtraerColores(app.ImagenOriginal);
            
            % --- EJE 1: CANAL ROJO ---
            cla(app.UIAxes3);
            app.UIAxes3.XLimMode = 'auto'; 
            app.UIAxes3.YLimMode = 'auto'; 
            imshow(ImgRed, 'Parent', app.UIAxes3);
            title(app.UIAxes3, 'Canal Rojo (R)'); 
            
            % --- EJE 2: CANAL VERDE ---
            cla(app.UIAxes3_2);
            app.UIAxes3_2.XLimMode = 'auto';
            app.UIAxes3_2.YLimMode = 'auto';
            imshow(ImgGreen, 'Parent', app.UIAxes3_2);
            title(app.UIAxes3_2, 'Canal Verde (G)');
            
            % --- EJE 3: CANAL AZUL ---
            cla(app.UIAxes3_3);
            app.UIAxes3_3.XLimMode = 'auto';
            app.UIAxes3_3.YLimMode = 'auto';
            imshow(ImgBlue, 'Parent', app.UIAxes3_3);
            title(app.UIAxes3_3, 'Canal Azul (B)');
        end

        % Button pushed function: MejoraImagenButton
        function MejoraImagenButtonPushed(app, event)
            % 1. Verificar que exista una imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Por favor, carga una imagen primero.', 'Advertencia');
                return;
            end
            
            % 👉 AQUÍ ESTABA EL BUG: Debemos jalar la MATRIZ, no el UIAxes
            imgActual = app.ImagenOriginal; 
            
            % 2. Asegurarnos de que la imagen base esté en escala de grises
            if size(imgActual, 3) == 3
                imgActual = obtenerGrisManual(imgActual);
            end
            
            % 3. Mandar a llamar tu función artesanal
            [imgEcualizada, H_original, H_ecualizado] = ecualizarAmano(imgActual);
            
            % 4. Mostrar las imágenes
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            
            imshow(imgActual, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Imagen en Grises');
            
            imshow(imgEcualizada, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Imagen Ecualizada');
            
            % 5. Mostrar los histogramas en los Axes multiusos
            bar(app.UIAxes3, 0:255, H_original, 'FaceColor', [0.2 0.6 0.8], 'EdgeColor', 'none');
            title(app.UIAxes3, 'Histograma Original');
            xlim(app.UIAxes3, [0 255]); 
            
            bar(app.UIAxes3_2, 0:255, H_ecualizado, 'FaceColor', [0.8 0.2 0.2], 'EdgeColor', 'none');
            title(app.UIAxes3_2, 'Histograma Ecualizado');
            xlim(app.UIAxes3_2, [0 255]);
        end

        % Button pushed function: CalcularDIstanciaButton
        function CalcularDIstanciaButtonPushed(app, event)
          % 1. Verificar que haya imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error de validación');
                return;
            end
            
            % --- CORTAFUEGOS ANTI-VACÍOS ---
            if isempty(app.X_size) || isempty(app.Y_size)
                uialert(app.UIFigure, 'Las dimensiones no están en memoria. Vuelve a presionar "Cargar Imagen".', 'Aviso');
                return;
            end
            
            % 2. Leer las coordenadas desde los campos numéricos
            x1 = round(app.X1EditField.Value);
            y1 = round(app.Y1EditField.Value);
            x2 = round(app.X2EditField.Value);
            y2 = round(app.Y2EditField.Value);
            
            P1 = [x1, y1];
            P2 = [x2, y2];
            
            % 3. Extraer dimensiones de forma segura
            maxX = app.X_size(1);
            maxY = app.Y_size(1);
            
            % 4. Revisar que los puntos sean PÍXELES REALES (De 1 en adelante)
            if P1(1) < 1 || P1(1) > maxX || P1(2) < 1 || P1(2) > maxY || ...
               P2(1) < 1 || P2(1) > maxX || P2(2) < 1 || P2(2) > maxY
                
                mensajeError = sprintf('Coordenadas inválidas.\nTu imagen va de X(1 a %d) y de Y(1 a %d).', maxX, maxY);
                uialert(app.UIFigure, mensajeError, 'Fuera de Límites');
                return;
            end
            
            % 5. PINTAR LOS PUNTOS EXACTOS EN LA PANTALLA (UIAxes de la Original)
            hold(app.UIAxes, 'on'); 
            plot(app.UIAxes, P1(1), P1(2), 'p', 'MarkerSize', 15, 'MarkerEdgeColor', 'm', 'MarkerFaceColor', 'y', 'LineWidth', 2);
            plot(app.UIAxes, P2(1), P2(2), 'p', 'MarkerSize', 15, 'MarkerEdgeColor', 'c', 'MarkerFaceColor', 'y', 'LineWidth', 2);
            plot(app.UIAxes, [P1(1), P2(1)], [P1(2), P2(2)], '-', 'Color', 'g', 'LineWidth', 2);
            hold(app.UIAxes, 'off'); 
            
            % 6. Leer qué distancia seleccionó
            tipoDistancia = app.DistanciasDropDown.Value; 
            
            d = 0; 
            
            % Calculamos la distancia llamando a tus librerías artesanales
            if strcmp(tipoDistancia, 'Euclidiana')
                d = distEuclidiana(P1, P2);
            elseif strcmp(tipoDistancia, 'Manhattan')
                d = distManhattan(P1, P2);
            elseif strcmp(tipoDistancia, 'Ajedrez') 
                d = distAjedrez(P1, P2);
            end
            
            % 7. Mostrar resultado en la interfaz
            app.ResultadoDistanciasLabel.Text = sprintf('Distancia %s: %.2f píxeles', tipoDistancia, d);
        end

        % Button pushed function: MostrarVecinosButton
        function MostrarVecinosButtonPushed(app, event)
           % 1. Verificar que haya imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error de validación');
                return;
            end
            
            % --- CORTAFUEGOS ANTI-VACÍOS ---
            if isempty(app.X_size) || isempty(app.Y_size)
                uialert(app.UIFigure, 'Las dimensiones no están en memoria. Vuelve a presionar "Cargar Imagen".', 'Aviso');
                return;
            end
            
            % 2. Leer las coordenadas desde los campos numéricos
            Px = round(app.X1_VecinosEditField.Value);
            Py = round(app.Y1_VecinosEditField.Value);
            
            % 3. Extraer dimensiones de forma segura (Escalares puros)
            maxX = app.X_size(1);
            maxY = app.Y_size(1);
            
            % 4. Revisar que el píxel seleccionado sea REAL y no se salga de la foto
            if Px < 1 || Px > maxX || Py < 1 || Py > maxY
                mensajeError = sprintf('Coordenada inválida.\nTu imagen va de X(1 a %d) y de Y(1 a %d).', maxX, maxY);
                uialert(app.UIFigure, mensajeError, 'Fuera de Límites');
                return;
            end
            
            % 5. Asegurarnos de tener la imagen en escala de grises para extraer los valores
            imgBase = app.ImagenOriginal;
            if size(imgBase, 3) == 3
                imgBase = obtenerGrisManual(imgBase);
            end
            
            % 6. PINTAR EL PÍXEL EXACTO EN LA PANTALLA PRINCIPAL
            hold(app.UIAxes, 'on');
            % Cruz 'x' grande color verde
            plot(app.UIAxes, Px, Py, 'x', 'MarkerSize', 20, 'MarkerEdgeColor', 'g', 'LineWidth', 3);
            % Punto '.' central magenta
            plot(app.UIAxes, Px, Py, '.', 'MarkerSize', 15, 'MarkerEdgeColor', 'm');
            hold(app.UIAxes, 'off');
            
            % 7. Leer qué vecindad seleccionó en el DropDown
            tipoVecindad = app.VecindadDropDown.Value;
            vecinos = [];
            
            % 8. Calcular los vecinos llamando a tus librerías artesanales
            if strcmp(tipoVecindad, '8-Vecinos')
                vecinos = obtenerVecindad8(imgBase, Px, Py, maxX, maxY);
            elseif strcmp(tipoVecindad, '4-Vecinos')
                vecinos = obtenerVecindad4(imgBase, Px, Py, maxX, maxY);
            elseif strcmp(tipoVecindad, 'D-Vecinos')
                vecinos = obtenerVecindadD(imgBase, Px, Py, maxX, maxY);
            end
            
            % 9. Convertir la matriz de salida a texto y mostrarla
            textoMatriz = mat2str(vecinos);
            
            % 🚨 CAMBIA "ResultadoVecinosLabel" POR EL NOMBRE REAL DE TU ETIQUETA EN APP DESIGNER
            app.ResultadoVecinosLabel.Text = sprintf('%s en X:%d, Y:%d -> %s', tipoVecindad, Px, Py, textoMatriz);
        end

        % Button pushed function: CalcularAritmeticaButton
        function CalcularAritmeticaButtonPushed(app, event)
            % 1. Validar que exista la imagen base
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen base.', 'Error');
                return;
            end
            
            % 2. Leer los valores de la Interfaz
            % Toma el valor que el usuario haya escrito en el NumericEditField
            C = app.ValorConstanteEditField.Value; 
            % Asegúrate de que el nombre del DropDown coincida con el de tu diseño
            tipoOperacion = app.AritmeticasDropDown.Value; 
            
            [filas, columnas, canales] = size(app.ImagenOriginal);
            imgResultado = zeros(filas, columnas, canales, 'uint8');
            
            % 3. Ejecutar la operación usando los métodos estáticos de la clase
            switch tipoOperacion
                % --- OPERACIONES CON ESCALAR ---
                case 'Suma Escalar'
                    imgResultado = OperacionesAritmeticas.sumaEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, canales);
                    
                case 'Resta Escalar'
                    imgResultado = OperacionesAritmeticas.restaEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, canales);
                    
                case 'Producto Escalar'
                    imgResultado = OperacionesAritmeticas.productoEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, canales);
                    
                case 'Division Escalar'
                    imgResultado = OperacionesAritmeticas.divisionEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, canales);
        
                % --- OPERACIONES ENTRE IMÁGENES ---
                case 'Suma Imagenes'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    % Se ajusta a mano sin usar toolbox
                    img2_ajustada = redimensionarManual(app.ImagenOriginal2, app.Y_size, app.X_size);
                    imgResultado = OperacionesAritmeticas.sumaImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
                    
                case 'Resta Imagenes Reales' % Revisa no tener espacios al final en las opciones de tu DropDown
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = redimensionarManual(app.ImagenOriginal2, app.Y_size, app.X_size);
                    
                    % OJO: Como tu compañero la diseñó para devolver "doubles" con negativos,
                    % hacemos un casteo a uint8 recortando los negativos a 0 para poder mostrarlo visualmente
                    resDouble = OperacionesAritmeticas.restaImagenesReal(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
                    imgResultado = uint8(max(0, resDouble)); 
        
                case 'Resta Imagenes Absoluta'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = redimensionarManual(app.ImagenOriginal2, app.Y_size, app.X_size);
                    imgResultado = OperacionesAritmeticas.restaImagenesAbsoluta(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
        
                case 'Producto Imagenes'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = redimensionarManual(app.ImagenOriginal2, app.Y_size, app.X_size);
                    imgResultado = OperacionesAritmeticas.productoImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
        
                case 'Division Imagenes'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = redimensionarManual(app.ImagenOriginal2, app.Y_size, app.X_size);
                    imgResultado = OperacionesAritmeticas.divisionImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
                    
                otherwise
                    uialert(app.UIFigure, 'Operación no válida o no reconocida.', 'Aviso');
                    return;
            end
            
            % 4. Mostrar el resultado final en la pantalla editada (UIAxes_2)
            cla(app.UIAxes_2); 
            imshow(imgResultado, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, tipoOperacion);
        end

        % Button pushed function: CalcularLogicaButton
        function CalcularLogicaButtonPushed(app, event)
            % 1. Validar que exista la imagen base
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga la imagen base.', 'Error');
                return;
            end
            
            % Cortafuegos de dimensiones
            if isempty(app.X_size) || isempty(app.Y_size)
                uialert(app.UIFigure, 'Las dimensiones no están en memoria. Vuelve a cargar la imagen.', 'Aviso');
                return;
            end
            
            % 2. Leer valores de la Interfaz
            tipoOperacion = app.LogicasDropDown.Value; % Cambia al nombre de tu DropDown
            C = app.ValorConstanteEditField.Value;     % Valor de la constante
            
            maxX = app.X_size(1);
            maxY = app.Y_size(1);
            canales = size(app.ImagenOriginal, 3);
            
            % 3. Evaluar la operación
            switch tipoOperacion
                case 'AND Escalar'
                    resLogico = OperacionesLogicas.andEscalar(app.ImagenOriginal, C, maxY, maxX, canales);
                    imgResultado = uint8(resLogico) * 255;
                    
                case 'OR Escalar'
                    resLogico = OperacionesLogicas.orEscalar(app.ImagenOriginal, C, maxY, maxX, canales);
                    imgResultado = uint8(resLogico) * 255;
                    
                case 'AND Imagenes'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = redimensionarManual(app.ImagenOriginal2, maxY, maxX);
                    resLogico = OperacionesLogicas.andImagenes(app.ImagenOriginal, img2_ajustada, maxY, maxX, canales);
                    imgResultado = uint8(resLogico) * 255;
                    
                case 'OR Imagenes'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = redimensionarManual(app.ImagenOriginal2, maxY, maxX);
                    resLogico = OperacionesLogicas.orImagenes(app.ImagenOriginal, img2_ajustada, maxY, maxX, canales);
                    imgResultado = uint8(resLogico) * 255;
                    
                case 'NOT Imagen'
                    % OJO: Tu función NOT ya devuelve uint8 de 0 a 255, no hay que multiplicarla
                    imgResultado = OperacionesLogicas.notImagen(app.ImagenOriginal, maxY, maxX, canales);
                    
                otherwise
                    uialert(app.UIFigure, 'Operación no válida.', 'Aviso');
                    return;
            end
            
            % 4. Mostrar resultado en la pantalla editada
            cla(app.UIAxes_2); 
            imshow(imgResultado, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, tipoOperacion);
        end

        % Button pushed function: CargarSegunaImagenButton
        function CargarSegunaImagenButtonPushed(app, event)
            % 1. Abrir explorador de archivos nativo
            [archivo, ruta] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Imágenes (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                'Selecciona la SEGUNDA imagen para operaciones compuestas');

            % Si el usuario cancela la selección, salimos pacíficamente
            if archivo == 0
                return;
            end

            % 2. Leer el archivo y almacenarlo en la propiedad global correcta
            rutaCompleta = fullfile(ruta, archivo);
            app.ImagenOriginal2 = imread(rutaCompleta);

            % 3. Limpiar y desplegar el contenido en el panel multiusos UIAxes3
            cla(app.UIAxes3); % Borra si antes había un histograma o canal de color
            imshow(app.ImagenOriginal2, 'Parent', app.UIAxes3);
            title(app.UIAxes3, 'Segunda Imagen Cargada');

            % Mensaje de confirmación en la consola para tus pruebas
            disp('Segunda imagen cargada con éxito en UIAxes3 y asignada a app.ImagenOriginal2.');
        end

        % Button pushed function: CalcularLogicaCompuestaButton
        function CalcularLogicaCompuestaButtonPushed(app, event)
            % 1. Validar que exista la imagen 1 y la imagen 2
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga la imagen base (Imagen 1).', 'Error');
                return;
            end
            if isempty(app.ImagenOriginal2)
                uialert(app.UIFigure, 'Debes cargar la SEGUNDA imagen para operaciones compuestas.', 'Error');
                return;
            end
            
            % Cortafuegos de dimensiones
            if isempty(app.X_size) || isempty(app.Y_size)
                uialert(app.UIFigure, 'Las dimensiones no están en memoria. Vuelve a cargar la imagen 1.', 'Aviso');
                return;
            end
            
            % 2. Leer parámetros
            tipoOperacion = app.LogicasCompuestasDropDown.Value; % Cambia al nombre de tu DropDown
            maxX = app.X_size(1);
            maxY = app.Y_size(1);
            canales = size(app.ImagenOriginal, 3);
            
            % 3. Redimensionar Imagen 2 artesanalmente para que encaje
            img2_ajustada = redimensionarManual(app.ImagenOriginal2, maxY, maxX);
            
            % 4. Ejecutar operaciones compuestas
            switch tipoOperacion
                case 'NAND Imagenes'
                    resLogico = OperacionesLogicasCompuestas.nandImagenes(app.ImagenOriginal, img2_ajustada, maxY, maxX, canales);
                    
                case 'NOR Imagenes'
                    resLogico = OperacionesLogicasCompuestas.norImagenes(app.ImagenOriginal, img2_ajustada, maxY, maxX, canales);
                    
                case 'XOR Imagenes'
                    resLogico = OperacionesLogicasCompuestas.xorImagenes(app.ImagenOriginal, img2_ajustada, maxY, maxX, canales);
                    
                case 'XNOR Imagenes'
                    resLogico = OperacionesLogicasCompuestas.xnorImagenes(app.ImagenOriginal, img2_ajustada, maxY, maxX, canales);
                    
                otherwise
                    uialert(app.UIFigure, 'Operación compuesta no válida.', 'Aviso');
                    return;
            end
            
            % 5. Convertir 1s y 0s lógicos a formato de imagen 255 y 0
            imgResultado = uint8(resLogico) * 255;
            
            % 6. Mostrar resultado
            cla(app.UIAxes_2); 
            imshow(imgResultado, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, tipoOperacion);
        end

        % Button pushed function: AplicarRuidoButton
        function AplicarRuidoButtonPushed(app, event)
           % 1. Validar que exista una imagen base cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Carga una imagen primero.', 'Advertencia');
                return;
            end
            
            tipoRuido = app.RuidoDropDown.Value;
            nivelStr = app.IntensidadDropDown.Value; 
            img = app.ImagenOriginal;
            
            % 2. Traducir la opción del menú a un valor numérico del 1 al 5
            switch nivelStr
                case {'Nivel 1 - Muy bajo', 'Nivel 1'}
                    val = 1; 
                case {'Nivel 2', 'Nivel 2 - Medio'} 
                    val = 2;
                case {'Nivel 3', 'Nivel 3 - Alto'}
                    val = 3;
                case {'Nivel 4', 'Nivel 4 - Muy Alto'}
                    val = 4;
                case 'Nivel 5'
                    val = 5;
                otherwise
                    val = 1;
            end
            
            % 3. Aplicar el tipo de ruido seleccionado
            switch tipoRuido
                case 'Sal y Pimienta'
                    densidades = [0.01, 0.03, 0.05, 0.10, 0.20];
                    app.ImagenConRuido = ruidoSalPimienta(img, densidades(val));
                case 'Gaussiano'
                    varianzas = [0.005, 0.01, 0.02, 0.05, 0.10];
                    app.ImagenConRuido = ruidoGaussiano(img, 0, varianzas(val));
                case 'Uniforme'
                    limitesB = [0.05, 0.1, 0.2, 0.3, 0.5];
                    app.ImagenConRuido = ruidoUniforme(img, -limitesB(val), limitesB(val));
                case 'Impulso'
                    probabilidades = [0.02, 0.05, 0.10, 0.15, 0.25];
                    app.ImagenConRuido = ruidoImpulso(img, probabilidades(val));
            end
            
            app.ImagenProcesada = app.ImagenConRuido;
            
            % 4. Mostrar el resultado en el SEGUNDO UI AXES (UIAxes_2)
            cla(app.UIAxes_2);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, sprintf('Ruido: %s (%s)', tipoRuido, nivelStr));
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
            % 1. Validar que exista la imagen con ruido previamente generada
            if isempty(app.ImagenConRuido)
                uialert(app.UIFigure, 'Primero debes aplicar ruido a la imagen.', 'Advertencia');
                return;
            end
            
            tipoFiltro = app.FiltroDropDown.Value;
            imgRuido = app.ImagenConRuido;
            
            % 2. Aplicar el filtro correspondiente
            switch tipoFiltro
                case 'Promedio'
                    app.ImagenProcesada = filtroPromedioManual(imgRuido, 3);
                case 'Mediana'
                    app.ImagenProcesada = filtroMedianaManual(imgRuido, 3);
                case 'Gaussiano'
                    app.ImagenProcesada = filtroGaussianoManual(imgRuido);
                case {'Bilateral', 'BIlaterial'} 
                    app.ImagenProcesada = filtroBilateralManual(imgRuido, 2.0, 30.0);
            end
            
            % 3. Mostrar el resultado filtrado en el SEGUNDO UI AXES (UIAxes_2)
            cla(app.UIAxes_2);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, sprintf('Restaurada con Filtro: %s', tipoFiltro));
        end

        % Button pushed function: CalcularGeometricasButton
        function CalcularGeometricasButtonPushed(app, event)
                % 1. Validar que exista la imagen base cargada
                if isempty(app.ImagenOriginal)
                    uialert(app.UIFigure, 'Primero debes cargar una imagen.', 'Error de validación');
                    return;
                end

                % 2. Leer la operación seleccionada
                tipoOperacion = app.GeometricasDropDown.Value;
                [filas, columnas, canales] = size(app.ImagenOriginal);

                imgResultado = [];

                % 3. Evaluar la transformación según las entradas del usuario
                switch tipoOperacion
                    case 'Traslación'
                        % Leer desplazamientos en X y Y
                        dX = round(app.DeltaXEditField.Value);
                        dY = round(app.DeltaYEditField.Value);

                        imgResultado = OperacionesGeometricas.trasladar(app.ImagenOriginal, dX, dY, app.Y_size, app.X_size, canales);

                    case 'Escalamiento'
                        % Leer el factor de escala
                        factor = app.FactorEditField.Value;

                        if factor <= 0
                            uialert(app.UIFigure, 'El factor de escala debe ser mayor a 0.', 'Error de Entrada');
                            return;
                        end

                        imgResultado = OperacionesGeometricas.escalar(app.ImagenOriginal, factor, app.Y_size, app.X_size, canales);

                    case 'Rotación'
                        % Leer el ángulo en grados
                        grados = app.GradosEditField.Value;

                        imgResultado = OperacionesGeometricas.rotar(app.ImagenOriginal, grados, app.Y_size, app.X_size, canales);

                    otherwise
                        uialert(app.UIFigure, 'Operación no válida.', 'Aviso');
                        return;
                end

                % 4. Mostrar el resultado en la pantalla de edición (UIAxes_2)
                cla(app.UIAxes_2);
                imshow(imgResultado, 'Parent', app.UIAxes_2);
                title(app.UIAxes_2, sprintf('Resultado: %s', tipoOperacion));
        end

        % Button pushed function: DescargarButton
        function DescargarButtonPushed(app, event)
            % 1. Validar que exista una imagen procesada para guardar
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, 'No hay ninguna imagen editada o procesada para descargar.', 'Aviso');
                return;
            end

            % 2. Abrir cuadro de diálogo nativo para guardar archivo
            [archivo, ruta] = uiputfile({...
                '*.png', 'Imagen PNG (*.png)'; ...
                '*.jpg', 'Imagen JPG (*.jpg)'; ...
                '*.bmp', 'Imagen BMP (*.bmp)'}, ...
                'Guardar Imagen Procesada', 'Imagen_Procesada.png');

            % Si el usuario cancela la ventana de guardado
            if archivo == 0
                return;
            end

            % 3. Guardar la matriz de la foto procesada en el disco
            rutaCompleta = fullfile(ruta, archivo);
            imwrite(app.ImagenProcesada, rutaCompleta);

            % 4. Confirmación elegante al usuario
            uialert(app.UIFigure, sprintf('Imagen guardada con éxito en:\n%s', rutaCompleta), ...
                'Guardado Exitoso', 'Icon', 'info')
        end

        % Button pushed function: ReiniciarEdicionButton
        function ReiniciarEdicionButtonPushed(app, event)
            % 1. Verificar si hay imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'No hay ninguna imagen cargada en la aplicación.', 'Aviso');
                return;
            end

            % 2. Reestablecer la imagen principal (limpia los plots, líneas y estrellas)
            hold(app.UIAxes, 'off');
            cla(app.UIAxes);
            imshow(app.ImagenOriginal, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'on');
            title(app.UIAxes, 'Imagen Original');

            % 3. Limpiar y desbloquear la pantalla de edición derecha (UIAxes_2)
            cla(app.UIAxes_2);
            app.UIAxes_2.XLimMode = 'auto';
            app.UIAxes_2.YLimMode = 'auto';
            title(app.UIAxes_2, 'Imagen Procesada / Resultado');

            % 4. Limpiar paneles multiusos / canales de color
            if isprop(app, 'UIAxes3')
                cla(app.UIAxes3);
                app.UIAxes3.XLimMode = 'auto'; app.UIAxes3.YLimMode = 'auto';
                title(app.UIAxes3, '');
            end
            if isprop(app, 'UIAxes3_2')
                cla(app.UIAxes3_2);
                app.UIAxes3_2.XLimMode = 'auto'; app.UIAxes3_2.YLimMode = 'auto';
                title(app.UIAxes3_2, '');
            end
            if isprop(app, 'UIAxes3_3')
                cla(app.UIAxes3_3);
                app.UIAxes3_3.XLimMode = 'auto'; app.UIAxes3_3.YLimMode = 'auto';
                title(app.UIAxes3_3, '');
            end

            % 5. Limpiar variables temporales de memoria
            app.ImagenProcesada = app.ImagenOriginal;
            app.ImagenConRuido = [];
            app.ImagenOriginal2 = [];

            % 6. Limpiar etiquetas de resultados (Ajusta nombres si varían en tu interfaz)
            if isprop(app, 'ResultadoLabel')
                app.ResultadoLabel.Text = 'Resultado: -';
            end
            if isprop(app, 'ResultadoVecinosLabel')
                app.ResultadoVecinosLabel.Text = 'Vecindad: -';
            end

            disp('Interfaz reiniciada al estado base de la imagen cargada.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Imagen Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Edicion')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.EscaladeGrisesButton = uibutton(app.Tab, 'push');
            app.EscaladeGrisesButton.ButtonPushedFcn = createCallbackFcn(app, @EscaladeGrisesButtonPushed, true);
            app.EscaladeGrisesButton.Text = 'Escala de Grises';
            app.ProcesamientoBasicoLabel = uilabel(app.Tab);
            app.ProcesamientoBasicoLabel.Text = 'Procesamiento Basico';
            app.BInarizacionButton = uibutton(app.Tab, 'push');
            app.BInarizacionButton.ButtonPushedFcn = createCallbackFcn(app, @BInarizacionButtonPushed, true);
            app.BInarizacionButton.Text = 'BInarizacion';
            app.SepararComponentesButton = uibutton(app.Tab, 'push');
            app.SepararComponentesButton.ButtonPushedFcn = createCallbackFcn(app, @SepararComponentesButtonPushed, true);
            app.SepararComponentesButton.Text = {'Separar Componentes '; 'De Color'};
            app.MejoraImagenButton = uibutton(app.Tab, 'push');
            app.MejoraImagenButton.ButtonPushedFcn = createCallbackFcn(app, @MejoraImagenButtonPushed, true);
            app.MejoraImagenButton.Text = 'Mejora De Imagen';
            app.EditFieldLabel = uilabel(app.Tab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Label = uilabel(app.Tab2);
            app.Label.Text = '';
            app.DistanciaDropDownLabel = uilabel(app.Tab2);
            app.DistanciaDropDownLabel.Text = 'Distancia';
            app.DistanciasDropDown = uidropdown(app.Tab2);
            app.DistanciasDropDown.Items = {'Euclidiana', 'Manhattan', 'Ajedrez'};
            app.DistanciasDropDown.Value = 'Euclidiana';
            app.X1EditFieldLabel = uilabel(app.Tab2);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.Tab2, 'numeric');
            app.VecindadDropDownLabel = uilabel(app.Tab2);
            app.VecindadDropDownLabel.Text = 'Vecindad';
            app.VecindadDropDown = uidropdown(app.Tab2);
            app.VecindadDropDown.Items = {'4', '8', 'D'};
            app.VecindadDropDown.Value = '4';
            app.CalcularDIstanciaButton = uibutton(app.Tab2, 'push');
            app.CalcularDIstanciaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularDIstanciaButtonPushed, true);
            app.CalcularDIstanciaButton.Text = 'Calcular DIstancia';
            app.MostrarVecinosButton = uibutton(app.Tab2, 'push');
            app.MostrarVecinosButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarVecinosButtonPushed, true);
            app.MostrarVecinosButton.Text = 'Mostrar Vecinos';
            app.AritmeticasDropDownLabel = uilabel(app.Tab2);
            app.AritmeticasDropDownLabel.Text = 'Aritmeticas';
            app.AritmeticasDropDown = uidropdown(app.Tab2);
            app.AritmeticasDropDown.Items = {'Suma Escalar', 'Resta Escalar', 'Producto Escalar', 'Division Escalar', 'Suma Imagenes', 'Resta Imagenes Reales', 'Resta Imagenes Absoluta', 'Producto Imagenes', 'Division Imagenes'};
            app.AritmeticasDropDown.Value = 'Suma Escalar';
            app.GeometricasDropDownLabel = uilabel(app.Tab2);
            app.GeometricasDropDownLabel.Text = 'Geometricas';
            app.GeometricasDropDown = uidropdown(app.Tab2);
            app.GeometricasDropDown.Items = {'Traslación', 'Escalamiento', 'Rotación'};
            app.GeometricasDropDown.Value = 'Traslación';
            app.LogicasDropDownLabel = uilabel(app.Tab2);
            app.LogicasDropDownLabel.Text = 'Logicas';
            app.LogicasDropDown = uidropdown(app.Tab2);
            app.LogicasDropDown.Items = {'AND Escalar', 'OR Escalar', 'AND Imagenes', 'OR Imagenes', 'NOT Imagen'};
            app.LogicasDropDown.Value = 'AND Escalar';
            app.LogicasCompuestasDropDownLabel = uilabel(app.Tab2);
            app.LogicasCompuestasDropDownLabel.Text = {'Logicas'; 'Compuestas'};
            app.LogicasCompuestasDropDown = uidropdown(app.Tab2);
            app.LogicasCompuestasDropDown.Items = {'NAND Imagenes', 'NOR Imagenes', 'XOR Imagenes', 'XNOR Imagenes'};
            app.LogicasCompuestasDropDown.Value = 'NAND Imagenes';
            app.Y1EditFieldLabel = uilabel(app.Tab2);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.Tab2, 'numeric');
            app.ValorConstanteLabel = uilabel(app.Tab2);
            app.ValorConstanteLabel.Text = {'Valor '; 'Constante'};
            app.ValorConstanteEditField = uieditfield(app.Tab2, 'numeric');
            app.CalcularAritmeticaButton = uibutton(app.Tab2, 'push');
            app.CalcularAritmeticaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularAritmeticaButtonPushed, true);
            app.CalcularAritmeticaButton.Text = 'Calcular';
            app.GradosEditField = uieditfield(app.Tab2, 'numeric');
            app.RotacinEditFieldLabel = uilabel(app.Tab2);
            app.RotacinEditFieldLabel.Text = 'Rotación';
            app.EscalamientoEditFieldLabel = uilabel(app.Tab2);
            app.EscalamientoEditFieldLabel.Text = 'Escalamiento';
            app.FactorEditField = uieditfield(app.Tab2, 'numeric');
            app.XEditFieldLabel = uilabel(app.Tab2);
            app.XEditFieldLabel.Text = 'X';
            app.DeltaXEditField = uieditfield(app.Tab2, 'numeric');
            app.YEditFieldLabel = uilabel(app.Tab2);
            app.YEditFieldLabel.Text = 'Y';
            app.DeltaYEditField = uieditfield(app.Tab2, 'numeric');
            app.TraslacionLabel = uilabel(app.Tab2);
            app.TraslacionLabel.Text = 'Traslacion';
            app.CalcularGeometricasButton = uibutton(app.Tab2, 'push');
            app.CalcularGeometricasButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularGeometricasButtonPushed, true);
            app.CalcularGeometricasButton.Text = 'Calcular';
            app.CalcularLogicaButton = uibutton(app.Tab2, 'push');
            app.CalcularLogicaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularLogicaButtonPushed, true);
            app.CalcularLogicaButton.Text = 'Calcular Logica';
            app.CalcularLogicaCompuestaButton = uibutton(app.Tab2, 'push');
            app.CalcularLogicaCompuestaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularLogicaCompuestaButtonPushed, true);
            app.CalcularLogicaCompuestaButton.Text = 'Calcular LC';
            app.X2EditFieldLabel = uilabel(app.Tab2);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.Tab2, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.Tab2);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.Tab2, 'numeric');
            app.X1EditField_2Label = uilabel(app.Tab2);
            app.X1EditField_2Label.Text = 'X1';
            app.X1_VecinosEditField = uieditfield(app.Tab2, 'numeric');
            app.Y1EditField_2Label = uilabel(app.Tab2);
            app.Y1EditField_2Label.Text = 'Y1';
            app.Y1_VecinosEditField = uieditfield(app.Tab2, 'numeric');
            app.ResultadoDistanciasLabel = uilabel(app.Tab2);
            app.ResultadoDistanciasLabel.Text = '';
            app.ResultadoVecinosLabel = uilabel(app.Tab2);
            app.ResultadoVecinosLabel.Text = '';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.RuidoyFIltrosLabel = uilabel(app.Tab3);
            app.RuidoyFIltrosLabel.Text = 'Ruido y FIltros';
            app.RuidoDropDownLabel = uilabel(app.Tab3);
            app.RuidoDropDownLabel.Text = 'Ruido';
            app.RuidoDropDown = uidropdown(app.Tab3);
            app.RuidoDropDown.Items = {'Sal y Pimienta', 'Gaussiano', 'Uniforme', 'Impulso'};
            app.RuidoDropDown.Value = 'Sal y Pimienta';
            app.IntensidadDropDown = uidropdown(app.Tab3);
            app.IntensidadDropDown.Items = {'Nivel 1 - Muy bajo', 'Nivel 2 - Medio', 'Nivel 3 - Alto', 'Nivel 4 - Muy Alto'};
            app.IntensidadDropDown.Value = 'Nivel 1 - Muy bajo';
            app.IntensidadDropDownLabel = uilabel(app.Tab3);
            app.IntensidadDropDownLabel.Text = 'Intensidad';
            app.AplicarRuidoButton = uibutton(app.Tab3, 'push');
            app.AplicarRuidoButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarRuidoButtonPushed, true);
            app.AplicarRuidoButton.Text = 'Aplicar Ruido';
            app.FiltroDropDownLabel = uilabel(app.Tab3);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.Tab3);
            app.FiltroDropDown.Items = {'Promedio', 'Mediana', 'Gaussiano', 'BIlaterial'};
            app.FiltroDropDown.Value = 'Promedio';
            app.AplicarFiltroButton = uibutton(app.Tab3, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar Filtro';
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.ReiniciarEdicionButton = uibutton(app.UIFigure, 'push');
            app.ReiniciarEdicionButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarEdicionButtonPushed, true);
            app.ReiniciarEdicionButton.Text = 'Reiniciar Edicion';
            app.DescargarButton = uibutton(app.UIFigure, 'push');
            app.DescargarButton.ButtonPushedFcn = createCallbackFcn(app, @DescargarButtonPushed, true);
            app.DescargarButton.Text = 'Descargar';
            app.CargarSegunaImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarSegunaImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarSegunaImagenButtonPushed, true);
            app.CargarSegunaImagenButton.Text = 'Cargar Seguna Imagen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end