classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ValorConstanteLogico            matlab.ui.control.NumericEditField
        ValorConstanteEditField_2Label  matlab.ui.control.Label
        CalcularLogicaCompuesta         matlab.ui.control.Button
        CargarsegundaimagenButton_2     matlab.ui.control.Button
        ValorConstanteEditField         matlab.ui.control.NumericEditField
        ValorConstanteEditFieldLabel    matlab.ui.control.Label
        OperacionesLogicasCompuestasDropDown  matlab.ui.control.DropDown
        OperacionesLogicasCompuestasDropDownLabel  matlab.ui.control.Label
        OperacionesGeometricasDropDownLabel_5  matlab.ui.control.Label
        OperacionesGeometricasDropDownLabel_4  matlab.ui.control.Label
        OperacionesGeometricasDropDownLabel_3  matlab.ui.control.Label
        OperacionesGeometricasDropDownLabel_2  matlab.ui.control.Label
        EscalamientoEditField           matlab.ui.control.NumericEditField
        EscalamientoEditFieldLabel      matlab.ui.control.Label
        RotacinEditFieldLabel           matlab.ui.control.Label
        RotacionEditField               matlab.ui.control.NumericEditField
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        LabelResultado                  matlab.ui.control.Label
        CalcularLogica                  matlab.ui.control.Button
        CalcularGeo                     matlab.ui.control.Button
        OperacionesLogicasDropDown      matlab.ui.control.DropDown
        OperacionesLogicasDropDownLabel  matlab.ui.control.Label
        OperacionesGeometricasDropDown  matlab.ui.control.DropDown
        OperacionesGeometricasDropDownLabel  matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        OperacionesAritmeticasDropDown  matlab.ui.control.DropDown
        OperacionesAritmeticasDropDownLabel  matlab.ui.control.Label
        MostrarVecinosButton            matlab.ui.control.Button
        DropDownVecinos                 matlab.ui.control.DropDown
        DropDownVecinosLabel            matlab.ui.control.Label
        DropDownDistancias              matlab.ui.control.DropDown
        DropDownDistanciasLabel         matlab.ui.control.Label
        CargarimagenButton              matlab.ui.control.Button
        CalcularDistanciaButton         matlab.ui.control.Button
        Segunda                         matlab.ui.control.UIAxes
        LogicaCompuesta                 matlab.ui.control.UIAxes
        ImgR                            matlab.ui.control.UIAxes
        ImgB                            matlab.ui.control.UIAxes
        ImgG                            matlab.ui.control.UIAxes
        OperacionLogica                 matlab.ui.control.UIAxes
        OperacionGeometrica             matlab.ui.control.UIAxes
        OperacionAritmetica             matlab.ui.control.UIAxes
        ImgGris                         matlab.ui.control.UIAxes
        ImgResultado                    matlab.ui.control.UIAxes
        ImgOriginal                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         ImagenOriginal  % Guarda la foto RGB a color
         ImagenOriginal2
         ImagenGris      % Guarda tu foto en escala de grises
         ImgRed
         ImgGreen
         ImgBlue
         X_size          % Ancho
         Y_size          % Alto
         X_size2
         Y_size2
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularDistanciaButton
        function calcDist(app, event)
    % Validamos que ya haya una imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error de validación');
                return;
            end

            % Avisamos al usuario
            app.LabelResultado.Text = 'Haz clic en el PRIMER punto (origen)...';
            pto1 = drawpoint(app.ImgOriginal); 
            
            app.LabelResultado.Text = 'Haz clic en el SEGUNDO punto (destino)...';
            pto2 = drawpoint(app.ImgOriginal);
            
            % Extraemos las coordenadas X e Y redondeadas
            P1 = [round(pto1.Position(1)), round(pto1.Position(2))];
            P2 = [round(pto2.Position(1)), round(pto2.Position(2))];
            
            % Borramos los puntitos visuales para limpiar la foto
            delete(pto1);
            delete(pto2);

            % Revisamos que los clics no se salgan del tamaño de la imagen
            if P1(1) < 1 || P1(1) > app.X_size || P1(2) < 1 || P1(2) > app.Y_size || ...
               P2(1) < 1 || P2(1) > app.X_size || P2(2) < 1 || P2(2) > app.Y_size
                
                app.LabelResultado.Text = 'Error: Diste clic fuera de la imagen :P';
                return;
            end
            
            % Leemos qué distancia seleccionó en el DropDown
            tipoDistancia = app.DropDownDistancias.Value;
            
            % Calculamos la distancia
            if strcmp(tipoDistancia, 'Euclidiana')
                d = distEuclidiana(P1, P2);
            elseif strcmp(tipoDistancia, 'Manhattan')
                d = distManhattan(P1, P2);
            elseif strcmp(tipoDistancia, 'Ajedrez')
                d = distAjedrez(P1, P2);
            end
            
            % Imprimimos el resultado final en la etiqueta
            app.LabelResultado.Text = sprintf('Distancia %s: %.2f píxeles', tipoDistancia, d);
        end

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
                [archivo, ruta] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Imagenes (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                    'Selecciona la imagen de tu equipo');
                if archivo == 0
                    return;
                end
                rutaCompleta = fullfile(ruta, archivo);
                app.ImagenOriginal = imread(rutaCompleta);

                imshow(app.ImagenOriginal, 'Parent', app.ImgOriginal);

                app.X_size = 0;
                app.Y_size = 0;

                for x = app.ImagenOriginal(1, 1:end, 1)
                    app.X_size = app.X_size + 1;
                end

                for y = app.ImagenOriginal(1:end, 1, 1)'
                    app.Y_size = app.Y_size + 1;
                end

                app.ImagenGris = obtenerGrisManual( app.ImagenOriginal);
                imshow(app.ImagenGris, 'Parent', app.ImgGris);

                [app.ImgRed, app.ImgGreen, app.ImgBlue] = ExtraerColores(app.ImagenOriginal,app.Y_size,app.X_size);
                
                imshow(app.ImgRed, 'Parent', app.ImgR);
                imshow(app.ImgGreen, 'Parent', app.ImgG);
                imshow(app.ImgBlue, 'Parent', app.ImgB);
                
                disp('Imagen cargada en los Axes y dimensiones calculadas.');
                disp('x')
                disp(app.X_size)
                disp('y')
                disp(app.Y_size)

        end

        % Button pushed function: MostrarVecinosButton
        function MostrarVecinosButtonPushed(app, event)

                % Validar que la imagen y su version en gris existan
                if isempty(app.ImagenOriginal)
                    uialert(app.UIFigure, 'Carga una imagen primero', 'Error');
                    return;
                end

                % Por seguridad, si la imagen gris no se generó, la forzamos aquí
                if isempty(app.ImagenGris)
                    app.ImagenGris = rgb2gray(app.ImagenOriginal);
                end

                % Pedimos el clic con drawpoint
                app.LabelResultado.Text = 'Haz 1 clic en el pixel central...';
                pto = drawpoint(app.ImgGris); 

                % Extraemos la coordenada y borramos el punto visual
                Px = round(pto.Position(1));
                Py = round(pto.Position(2));
                delete(pto);

                % Validar límites para no salirnos de la matriz
                if Px < 1 || Px > app.X_size || Py < 1 || Py > app.Y_size
                    app.LabelResultado.Text = 'Error: Clic fuera de la matriz.';
                    return;
                end

                % Leemos qué vecindad seleccionó en el DropDown
                tipoVecindad = app.DropDownVecinos.Value;

                vecinos = [];
                % Calculamos los vecinos llamando a tus funciones personalizadas 
                if strcmp(tipoVecindad, '8-Vecinos')
                    vecinos = obtenerVecindad8(app,app.ImagenGris, Px, Py);
                elseif strcmp(tipoVecindad, '4-Vecinos')
                    vecinos = obtenerVecindad4(app,app.ImagenGris, Px, Py);
                elseif strcmp(tipoVecindad, 'D-Vecinos')
                    vecinos = obtenerVecindadD(app,app.ImagenGris, Px, Py);
                end

                % Convertimos el arreglo de salida en texto y lo imprimimos
                textoMatriz = mat2str(vecinos);
                app.LabelResultado.Text = sprintf('%s: %s', tipoVecindad, textoMatriz);
        end

        % Button pushed function: CalcularButton
        function Aritmeticas(app, event)
               if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                return;
                end

                C = app.ValorConstanteEditField.Value;
                
                tipoOperacion = app.OperacionesAritmeticasDropDown.Value;
                
                imgResultado = zeros(app.X_size, app.Y_size, 3, 'uint8');
                
                switch tipoOperacion
            % --- OPERACIONES CON ESCALAR ---
                    case 'Suma Escalar'
                        if isempty(app.ValorConstanteEditField.Value) 
                            uialert(app.UIFigure, 'Primero da un valor.', 'Error');
                            return;
                        end
                        imgResultado = sumaEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, 3);
                        
                    case 'Resta Escalar'
                        if isempty(app.ValorConstanteEditField.Value) 
                            uialert(app.UIFigure, 'Primero da un valor.', 'Error');
                            return;
                        end
                        imgResultado = restaEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, 3);
                        
                    case 'Producto Escalar'
                        if isempty(app.ValorConstanteEditField.Value) 
                            uialert(app.UIFigure, 'Primero da un valor.', 'Error');
                            return;
                        end
                        imgResultado = productoEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, 3);
                        
                    case 'Division Escalar'
                        if isempty(app.ValorConstanteEditField.Value) 
                            uialert(app.UIFigure, 'Primero da un valor.', 'Error');
                            return;
                        end
                        imgResultado = divisionEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, 3);
            
                    % --- OPERACIONES ENTRE IMÁGENES ---
                    case 'Suma Imagenes'
                        if isempty(app.ImagenOriginal2)
                            uialert(app.UIFigure, 'Carga la segunda imagen primero.', 'Error');
                            return;
                        end
                        img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                        imgResultado = sumaImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, 3);
                        
                    case 'Resta de Imagenes reales '
                        if isempty(app.ImagenOriginal2)
                            uialert(app.UIFigure, 'Carga la segunda imagen primero.', 'Error');
                            return;
                        end
                        img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                        imgResultado = restaImagenesReal(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, 3);
            
                    case 'Resta de Imagenes absoluta'
                        if isempty(app.ImagenOriginal2)
                            uialert(app.UIFigure, 'Carga la segunda imagen primero.', 'Error');
                            return;
                        end
                        img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                        imgResultado =restaImagenesAbsoluta(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, 3);
            
                    case 'Producto Imagenes'
                        if isempty(app.ImagenOriginal2)
                            uialert(app.UIFigure, 'Carga la segunda imagen primero.', 'Error');
                            return;
                        end
                        img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                        imgResultado = productoImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, 3);
            
                    case 'Division Imagenes'
                        if isempty(app.ImagenOriginal2)
                            uialert(app.UIFigure, 'Carga la segunda imagen primero.', 'Error');
                            return;
                        end
                        img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                        imgResultado = divisionImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, 3);
                        
                    otherwise
                        uialert(app.UIFigure, 'Operación no válida o en desarrollo.', 'Aviso');
                        return;
                   end
                
                % 5. Mostrar el resultado en los ejes
                imshow(imgResultado, 'Parent', app.OperacionAritmetica);
        
        end

        % Button pushed function: CargarsegundaimagenButton_2
        function CargarsegundaimagenButton_2Pushed(app, event)
                [archivo2, ruta2] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Imagenes (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                    'Selecciona la imagen de tu equipo');
                if archivo2 == 0
                    return;
                end
                rutaCompleta2 = fullfile(ruta2, archivo2);
                app.ImagenOriginal2 = imread(rutaCompleta2);

                imshow(app.ImagenOriginal2, 'Parent', app.Segunda);

                app.X_size2 = 0;
                app.Y_size2 = 0;

                for x = app.ImagenOriginal2(1, 1:end, 1)
                    app.X_size2 = app.X_size2 + 1;
                end

                for y = app.ImagenOriginal2(1:end, 1, 1)'
                    app.Y_size2 = app.Y_size2 + 1;
                end
        end

        % Button pushed function: CalcularGeo
        function CalcularGeoButtonPushed(app, event)
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                return;
            end

            R = app.RotacionEditField.Value;
            Tx = app.XEditField.Value ;
            Ty = app.YEditField.Value;
            E = app.EscalamientoEditField.Value;

            tipoGeometrico= app.OperacionesGeometricasDropDown.Value;
                
            imgResultadoG = zeros(app.Y_size, app.X_size, 3, 'uint8');
                
            switch tipoGeometrico
                case 'Escalamiento'
                        if E == 0 
                            uialert(app.UIFigure, 'Primero da un valor al Escalamiento.', 'Error');
                            return;
                        end
                        imgResultadoG = escalar(app.ImagenOriginal, E, app.Y_size, app.X_size, 3);
                        
                case 'Traslación'
                        if Tx == 0  || Ty == 0  
                            uialert(app.UIFigure, 'Primero da un valor de Traslación.', 'Error');
                            return;
                        end
                        imgResultadoG = trasladar(app.ImagenOriginal, Tx, Ty);
                        
                case 'Rotación'
                        if R == 0  
                            uialert(app.UIFigure, 'Primero da un valor de rotación en grados.', 'Error');
                            return;
                        end
                        imgResultadoG = rotar(app.ImagenOriginal, R);
            end         
                % 5. Mostrar el resultado en los ejes
                imshow(imgResultadoG, 'Parent', app.OperacionGeometrica);
            
        end

        % Button pushed function: CalcularLogica
        function CalcularLogicaButtonPushed(app, event)
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                return;
            end

            tipoLogico= app.OperacionesLogicasDropDown.Value;
            cLogico = app.ValorConstanteLogico.Value;
                
            imgResultadoL = zeros(app.Y_size,app.X_size, 3, 'uint8');
            img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                
            switch tipoLogico
                case 'AND Escalar'
                    if cLogico == 0 
                        uialert(app.UIFigure, 'Primero da un valor escalar.', 'Error');
                        return;
                    end

                    imgResultadoL = andEscalar(app.ImagenOriginal,cLogico, app.Y_size,app.X_size,3);
                

                case 'AND Imagenes'
                    if isempty(app.ImagenOriginal2)
                            uialert(app.UIFigure, 'Carga la segunda imagen primero.', 'Error');
                            return;
                     end
                     imgResultadoL = andImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size,app.X_size,3);
                case 'OR Escalar'
                      if cLogico == 0 
                            uialert(app.UIFigure, 'Primero da un valor escalar.', 'Error');
                            return;
                      end

                        imgResultadoL = orEscalar(app.ImagenOriginal,cLogico,app.Y_size,app.X_size,3);
                case 'OR Imagenes'
                     if isempty(app.ImagenOriginal2)
                            uialert(app.UIFigure, 'Carga la segunda imagen primero.', 'Error');
                            return;
                     end
    
                     imgResultadoL = orImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size,app.X_size,3);
                        
                case 'NOT'
                      
                    imgResultadoL = notImagen(app.ImagenOriginal,  app.Y_size,app.X_size,3);
                        
            end
            disp(imgResultadoL)
            imshow(imgResultadoL, 'Parent', app.OperacionLogica);

        end

        % Button pushed function: CalcularLogicaCompuesta
        function CalcularLogicaCompuestaButtonPushed(app, event)
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                return;
            end

            tipoLogicoCompuesto= app.OperacionesLogicasCompuestasDropDown.Value;
            imgResultadoLC = zeros(app.Y_size,app.X_size, 3, 'uint8');
            img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
            switch tipoLogicoCompuesto
                case 'NOR' 
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                        return;
                    end

                    imgResultadoLC = norImagenes(app.ImagenOriginal,img2_ajustada , app.Y_size,app.X_size,3);
                        
                case 'NAND'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                        return;
                    end
                    imgResultadoLC = nandImagenes(app.ImagenOriginal,img2_ajustada , app.Y_size,app.X_size,3);
                        
                case 'XOR'
                     if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                        return;
                     end
                     imgResultadoLC = xorImagenes(app.ImagenOriginal,img2_ajustada , app.Y_size,app.X_size,3);
                case 'XNOR'
                     if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                        return;
                     end
                        imgResultadoLC = xnorImagenes(app.ImagenOriginal,img2_ajustada , app.Y_size,app.X_size,3);
            end
           
                        
                % 5. Mostrar el resultado en los ejes
                imshow(imgResultadoLC, 'Parent', app.OperacionLogicaCompuesta);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImgOriginal = uiaxes(app.UIFigure);
            title(app.ImgOriginal, 'Imagen Original')
            app.ImgOriginal.XTick = [];
            app.ImgOriginal.YTick = [];
            app.ImgResultado = uiaxes(app.UIFigure);
            title(app.ImgResultado, 'Resultados')
            app.ImgResultado.XTick = [];
            app.ImgResultado.XTickLabel = '';
            app.ImgResultado.YTick = [];
            app.ImgGris = uiaxes(app.UIFigure);
            title(app.ImgGris, 'Escala de Grises')
            app.ImgGris.XTick = [];
            app.ImgGris.XTickLabel = '';
            app.ImgGris.YTick = [];
            app.OperacionAritmetica = uiaxes(app.UIFigure);
            title(app.OperacionAritmetica, 'Operación Aritmetica')
            app.OperacionAritmetica.XTick = [];
            app.OperacionAritmetica.YTick = [];
            app.OperacionGeometrica = uiaxes(app.UIFigure);
            title(app.OperacionGeometrica, 'Operacion Geometrica')
            app.OperacionGeometrica.XTick = [];
            app.OperacionGeometrica.YTick = [];
            app.OperacionLogica = uiaxes(app.UIFigure);
            title(app.OperacionLogica, 'Operación Logica')
            app.OperacionLogica.XTick = [];
            app.OperacionLogica.YTick = [];
            app.ImgG = uiaxes(app.UIFigure);
            app.ImgG.XTick = [];
            app.ImgG.XTickLabel = '';
            app.ImgG.YTick = [];
            app.ImgB = uiaxes(app.UIFigure);
            app.ImgB.XTick = [];
            app.ImgB.XTickLabel = '';
            app.ImgB.YTick = [];
            app.ImgR = uiaxes(app.UIFigure);
            title(app.ImgR, 'Imagen separada')
            app.ImgR.XTick = [];
            app.ImgR.XTickLabel = '';
            app.ImgR.YTick = [];
            app.LogicaCompuesta = uiaxes(app.UIFigure);
            title(app.LogicaCompuesta, 'Operación Logica')
            app.LogicaCompuesta.XTick = [];
            app.LogicaCompuesta.YTick = [];
            app.Segunda = uiaxes(app.UIFigure);
            title(app.Segunda, 'Segunda Imagen')
            app.Segunda.XTick = [];
            app.Segunda.YTick = [];
            app.CalcularDistanciaButton = uibutton(app.UIFigure, 'push');
            app.CalcularDistanciaButton.ButtonPushedFcn = createCallbackFcn(app, @calcDist, true);
            app.CalcularDistanciaButton.Text = 'Calcular Distancia';
            app.CargarimagenButton = uibutton(app.UIFigure, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.DropDownDistanciasLabel = uilabel(app.UIFigure);
            app.DropDownDistanciasLabel.Text = 'DropDownDistancias';
            app.DropDownDistancias = uidropdown(app.UIFigure);
            app.DropDownDistancias.Items = {'Euclidiana', 'Manhattan', 'Ajedrez'};
            app.DropDownDistancias.Value = 'Euclidiana';
            app.DropDownVecinosLabel = uilabel(app.UIFigure);
            app.DropDownVecinosLabel.Text = 'DropDownVecinos';
            app.DropDownVecinos = uidropdown(app.UIFigure);
            app.DropDownVecinos.Items = {'4-Vecinos', '8-Vecinos', 'D-Vecinos'};
            app.DropDownVecinos.Value = '4-Vecinos';
            app.MostrarVecinosButton = uibutton(app.UIFigure, 'push');
            app.MostrarVecinosButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarVecinosButtonPushed, true);
            app.MostrarVecinosButton.Text = 'Mostrar Vecinos';
            app.OperacionesAritmeticasDropDownLabel = uilabel(app.UIFigure);
            app.OperacionesAritmeticasDropDownLabel.Text = 'Operaciones Aritmeticas';
            app.OperacionesAritmeticasDropDown = uidropdown(app.UIFigure);
            app.OperacionesAritmeticasDropDown.Items = {'Suma Escalar', 'Suma Imagenes', 'Resta Escalar', 'Resta de Imagenes reales ', 'Resta de Imagenes absoluta', 'Producto Escalar', 'Producto Imagenes', 'Division Escalar', 'Division Imagenes'};
            app.OperacionesAritmeticasDropDown.Value = 'Suma Escalar';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @Aritmeticas, true);
            app.CalcularButton.Text = 'Calcular';
            app.OperacionesGeometricasDropDownLabel = uilabel(app.UIFigure);
            app.OperacionesGeometricasDropDownLabel.Text = 'Operaciones Geometricas';
            app.OperacionesGeometricasDropDown = uidropdown(app.UIFigure);
            app.OperacionesGeometricasDropDown.Items = {'Rotación', 'Escalamiento', 'Traslación'};
            app.OperacionesGeometricasDropDown.Value = 'Rotación';
            app.OperacionesLogicasDropDownLabel = uilabel(app.UIFigure);
            app.OperacionesLogicasDropDownLabel.Text = 'Operaciones Logicas';
            app.OperacionesLogicasDropDown = uidropdown(app.UIFigure);
            app.OperacionesLogicasDropDown.Items = {'AND Escalar', 'AND Imagenes', 'OR Escalar', 'OR Imagenes', 'NOT'};
            app.OperacionesLogicasDropDown.Value = 'AND Escalar';
            app.CalcularGeo = uibutton(app.UIFigure, 'push');
            app.CalcularGeo.ButtonPushedFcn = createCallbackFcn(app, @CalcularGeoButtonPushed, true);
            app.CalcularGeo.Text = 'Calcular';
            app.CalcularLogica = uibutton(app.UIFigure, 'push');
            app.CalcularLogica.ButtonPushedFcn = createCallbackFcn(app, @CalcularLogicaButtonPushed, true);
            app.CalcularLogica.Text = 'Calcular';
            app.LabelResultado = uilabel(app.UIFigure);
            app.LabelResultado.Text = 'LabelResultado';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotacionEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotacinEditFieldLabel = uilabel(app.UIFigure);
            app.RotacinEditFieldLabel.Text = 'Rotación';
            app.EscalamientoEditFieldLabel = uilabel(app.UIFigure);
            app.EscalamientoEditFieldLabel.Text = 'Escalamiento';
            app.EscalamientoEditField = uieditfield(app.UIFigure, 'numeric');
            app.OperacionesGeometricasDropDownLabel_2 = uilabel(app.UIFigure);
            app.OperacionesGeometricasDropDownLabel_2.Text = 'Traslación';
            app.OperacionesGeometricasDropDownLabel_3 = uilabel(app.UIFigure);
            app.OperacionesGeometricasDropDownLabel_3.Text = 'R';
            app.OperacionesGeometricasDropDownLabel_4 = uilabel(app.UIFigure);
            app.OperacionesGeometricasDropDownLabel_4.Text = 'G';
            app.OperacionesGeometricasDropDownLabel_5 = uilabel(app.UIFigure);
            app.OperacionesGeometricasDropDownLabel_5.Text = 'B';
            app.OperacionesLogicasCompuestasDropDownLabel = uilabel(app.UIFigure);
            app.OperacionesLogicasCompuestasDropDownLabel.Text = 'Operaciones Logicas Compuestas';
            app.OperacionesLogicasCompuestasDropDown = uidropdown(app.UIFigure);
            app.OperacionesLogicasCompuestasDropDown.Items = {'NAND ', 'XOR', 'NOR', 'XNOR'};
            app.OperacionesLogicasCompuestasDropDown.Value = 'NAND ';
            app.ValorConstanteEditFieldLabel = uilabel(app.UIFigure);
            app.ValorConstanteEditFieldLabel.Text = 'Valor Constante';
            app.ValorConstanteEditField = uieditfield(app.UIFigure, 'numeric');
            app.CargarsegundaimagenButton_2 = uibutton(app.UIFigure, 'push');
            app.CargarsegundaimagenButton_2.ButtonPushedFcn = createCallbackFcn(app, @CargarsegundaimagenButton_2Pushed, true);
            app.CargarsegundaimagenButton_2.Text = 'Cargar segunda imagen';
            app.CalcularLogicaCompuesta = uibutton(app.UIFigure, 'push');
            app.CalcularLogicaCompuesta.ButtonPushedFcn = createCallbackFcn(app, @CalcularLogicaCompuestaButtonPushed, true);
            app.CalcularLogicaCompuesta.Text = 'Calcular';
            app.ValorConstanteEditField_2Label = uilabel(app.UIFigure);
            app.ValorConstanteEditField_2Label.Text = 'Valor Constante';
            app.ValorConstanteLogico = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end