classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ReiniciarValoresButton       matlab.ui.control.Button
        AplicarFiltroButton          matlab.ui.control.Button
        FiltroDropDown               matlab.ui.control.DropDown
        FiltroDropDownLabel          matlab.ui.control.Label
        AplicarRuidoButton           matlab.ui.control.Button
        IntensidadDropDown           matlab.ui.control.DropDown
        IntensidadDropDownLabel      matlab.ui.control.Label
        RuidoDropDown                matlab.ui.control.DropDown
        RuidoDropDownLabel           matlab.ui.control.Label
        BinarizarBlancoyNegroButton  matlab.ui.control.Button
        EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        EscaladeGrisesButton         matlab.ui.control.Button
        CargarImagenButton           matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImagenOriginal      % Almacena la matriz de la imagen cargada
        ImagenProcesada     % Almacena la matriz en proceso actual
        ImagenConRuido      % Almacena la matriz contaminada con ruido
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
            [archivo, ruta] = uigetfile({'*.jpg;*.png;*.bmp','Archivos de Imagen'});
            if archivo ~= 0
                rutaCompleta = fullfile(ruta, archivo);
                app.ImagenOriginal = imread(rutaCompleta);
                app.ImagenProcesada = app.ImagenOriginal; 
                imshow(app.ImagenOriginal, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: EscaladeGrisesButton
        function EscaladeGrisesButtonPushed(app, event)
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero debes cargar una imagen.', 'Advertencia');
                return;
            end
            % Mandar a llamar tu función independiente de grises
            app.ImagenProcesada = obtenerGrisManual(app.ImagenOriginal);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes);
        end

        % Button pushed function: BinarizarBlancoyNegroButton
        function BinarizarBlancoyNegroButtonPushed(app, event)
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, 'No hay imagen procesada para binarizar.', 'Advertencia');
                return;
            end
            
            imgActual = app.ImagenProcesada;
            if size(imgActual, 3) == 3
                imgActual = obtenerGrisManual(imgActual);
            end
            
            % Leer el umbral del campo de texto numérico
            umbralUsuario = app.EditField.Value;
            
            % Mandar a llamar tu función independiente de umbral/binario
            app.ImagenProcesada = binarizarManual(imgActual, umbralUsuario);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes);
        end

        % Button pushed function: AplicarRuidoButton
        function AplicarRuidoButtonPushed(app, event)
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Carga una imagen primero.', 'Advertencia');
                return;
            end
            
            tipoRuido = app.RuidoDropDown.Value;
            nivelStr = app.IntensidadDropDown.Value; 
            img = app.ImagenOriginal;
            
            % Traducir la opción del menú a un valor numérico del 1 al 5
            switch nivelStr
                case {'Nivel 1 - Muy bajo', 'Nivel 1'}
                    val = 1; 
                case {'Nivel 2', 'Nivel 2 - Medio'} % Dependiendo de qué texto tengas en el menú
                    val = 2;
                case {'Nivel 3', 'Nivel 3 - Alto'}
                    val = 3;
                case {'Nivel 4', 'Nivel 4 - Muy Alto'}
                    val = 4;
                case 'Nivel 5'
                    val = 5;
                otherwise
                    val = 1;
            end
            
            switch tipoRuido
                case 'Sal y Pimienta'
                    densidades = [0.01, 0.03, 0.05, 0.10, 0.20];
                    app.ImagenConRuido = ruidoSalPimienta(img, densidades(val));
                case 'Gaussiano'
                    varianzas = [0.005, 0.01, 0.02, 0.05, 0.10];
                    app.ImagenConRuido = ruidoGaussiano(img, 0, varianzas(val));
                case 'Uniforme'
                    limitesB = [0.05, 0.1, 0.2, 0.3, 0.5];
                    app.ImagenConRuido = ruidoUniforme(img, -limitesB(val), limitesB(val));
                case 'Impulso'
                    probabilidades = [0.02, 0.05, 0.10, 0.15, 0.25];
                    app.ImagenConRuido = ruidoImpulso(img, probabilidades(val));
            end
            
            app.ImagenProcesada = app.ImagenConRuido;
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes);
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
            if isempty(app.ImagenConRuido)
                uialert(app.UIFigure, 'Primero debes aplicar ruido a la imagen.', 'Advertencia');
                return;
            end
            
            tipoFiltro = app.FiltroDropDown.Value;
            imgRuido = app.ImagenConRuido;
            
            switch tipoFiltro
                case 'Promedio'
                    app.ImagenProcesada = filtroPromedioManual(imgRuido, 3);
                case 'Mediana'
                    app.ImagenProcesada = filtroMedianaManual(imgRuido, 3);
                case 'Gaussiano'
                    app.ImagenProcesada = filtroGaussianoManual(imgRuido);
                case {'Bilateral', 'BIlaterial'} % Cubriendo el pequeño detalle de dedo en tu menú
                    app.ImagenProcesada = filtroBilateralManual(imgRuido, 2.0, 30.0);
            end
            
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.EscaladeGrisesButton = uibutton(app.UIFigure, 'push');
            app.EscaladeGrisesButton.ButtonPushedFcn = createCallbackFcn(app, @EscaladeGrisesButtonPushed, true);
            app.EscaladeGrisesButton.Text = 'Escala de Grises';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.BinarizarBlancoyNegroButton = uibutton(app.UIFigure, 'push');
            app.BinarizarBlancoyNegroButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizarBlancoyNegroButtonPushed, true);
            app.BinarizarBlancoyNegroButton.Text = 'Binarizar (Blanco y Negro)';
            app.RuidoDropDownLabel = uilabel(app.UIFigure);
            app.RuidoDropDownLabel.Text = 'Ruido';
            app.RuidoDropDown = uidropdown(app.UIFigure);
            app.RuidoDropDown.Items = {'Sal y Pimienta', 'Gaussiano', 'Uniforme', 'Impulso'};
            app.RuidoDropDown.Value = 'Sal y Pimienta';
            app.IntensidadDropDownLabel = uilabel(app.UIFigure);
            app.IntensidadDropDownLabel.Text = 'Intensidad';
            app.IntensidadDropDown = uidropdown(app.UIFigure);
            app.IntensidadDropDown.Items = {'Nivel 1 - Muy bajo', 'Nivel 2 - Medio', 'Nivel 3 - Alto', 'Nivel 4 - Muy Alto'};
            app.IntensidadDropDown.Value = 'Nivel 1 - Muy bajo';
            app.AplicarRuidoButton = uibutton(app.UIFigure, 'push');
            app.AplicarRuidoButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarRuidoButtonPushed, true);
            app.AplicarRuidoButton.Text = 'Aplicar Ruido';
            app.FiltroDropDownLabel = uilabel(app.UIFigure);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.UIFigure);
            app.FiltroDropDown.Items = {'Promedio', 'Mediana', 'Gaussiano', 'BIlaterial'};
            app.FiltroDropDown.Value = 'Promedio';
            app.AplicarFiltroButton = uibutton(app.UIFigure, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar Filtro';
            app.ReiniciarValoresButton = uibutton(app.UIFigure, 'push');
            app.ReiniciarValoresButton.Text = 'Reiniciar Valores';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end